classdef ExampleAlign < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AlignExampleUIFigure    matlab.ui.Figure
        RandomizeButton         matlab.ui.control.Button
        PlaygroundPanel         matlab.ui.container.Panel
        Button                  matlab.ui.control.Button
        EditField               matlab.ui.control.NumericEditField
        Label                   matlab.ui.control.Label
        RefObj                  matlab.ui.control.Label
        AlignhorizontallyPanel  matlab.ui.container.Panel
        CenterButton            matlab.ui.control.Button
        LeftButton              matlab.ui.control.Button
        RightButton             matlab.ui.control.Button
        FillButton              matlab.ui.control.Button
        AlignverticallyPanel    matlab.ui.container.Panel
        CenterButton_2          matlab.ui.control.Button
        BottomButton            matlab.ui.control.Button
        TopButton               matlab.ui.control.Button
        FillButton_2            matlab.ui.control.Button
        AlighbothPanel          matlab.ui.container.Panel
        CCButton                matlab.ui.control.Button
        LCButton                matlab.ui.control.Button
        RCButton                matlab.ui.control.Button
        LBButton                matlab.ui.control.Button
        CBButton                matlab.ui.control.Button
        RBButton                matlab.ui.control.Button
        LTButton                matlab.ui.control.Button
        CTButton                matlab.ui.control.Button
        RTButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        Objs % Objects to align
        DefPoses % Default positions
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Objs = [app.Button app.EditField app.Label];
            app.DefPoses = get(app.Objs, {'Position'});
            app.DefPoses = vertcat(app.DefPoses{:});
            app.RandomizeButtonPushed();
        end

        % Button pushed function: RandomizeButton
        function RandomizeButtonPushed(app, event)
            W = app.PlaygroundPanel.Position(3) * 0.8;
            H = app.PlaygroundPanel.Position(4) * 0.8;
            poses = get(app.Objs, {'Position'});
            poses = vertcat(poses{:});
            xs = rand(size(poses, 1), 1) * W;
            ys = rand(size(poses, 1), 1) * H;
            poses = [xs ys app.DefPoses(:, [3 4])];
            set(app.Objs, {'Position'}, num2cell(poses, 2))
        end

        % Button pushed function: CCButton
        function CCButtonPushed(app, event)
            uialign(app.Objs, app.RefObj, 'center', 'center');
        end

        % Button pushed function: LCButton
        function LCButtonPushed(app, event)
            uialign(app.Objs, app.RefObj, 'left', 'center');
        end

        % Button pushed function: RCButton
        function RCButtonPushed(app, event)
            uialign(app.Objs, app.RefObj, 'right', 'center');
        end

        % Button pushed function: LBButton
        function LBButtonPushed(app, event)
            uialign(app.Objs, app.RefObj, 'left', 'bottom');
        end

        % Button pushed function: CBButton
        function CBButtonPushed(app, event)
            uialign(app.Objs, app.RefObj, 'center', 'bottom');
        end

        % Button pushed function: RBButton
        function RBButtonPushed(app, event)
            uialign(app.Objs, app.RefObj, 'right', 'bottom');
        end

        % Button pushed function: LTButton
        function LTButtonPushed(app, event)
            uialign(app.Objs, app.RefObj, 'left', 'top');
        end

        % Button pushed function: CTButton
        function CTButtonPushed(app, event)
            uialign(app.Objs, app.RefObj, 'center', 'top');
        end

        % Button pushed function: RTButton
        function RTButtonPushed(app, event)
            uialign(app.Objs, app.RefObj, 'right', 'top');
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            uialign(app.Objs, app.RefObj, 'left', '');
        end

        % Button pushed function: CenterButton
        function CenterButtonPushed(app, event)
            uialign(app.Objs, app.RefObj, 'center', '');
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            uialign(app.Objs, app.RefObj, 'right', '');
        end

        % Button pushed function: FillButton
        function FillButtonPushed(app, event)
            uialign(app.Objs, app.RefObj, 'fill', '');
        end

        % Button pushed function: TopButton
        function TopButtonPushed(app, event)
            uialign(app.Objs, app.RefObj, '', 'top');
        end

        % Button pushed function: CenterButton_2
        function CenterButton_2Pushed(app, event)
            uialign(app.Objs, app.RefObj, '', 'center');
        end

        % Button pushed function: BottomButton
        function BottomButtonPushed(app, event)
            uialign(app.Objs, app.RefObj, '', 'bottom');
        end

        % Button pushed function: FillButton_2
        function FillButton_2Pushed(app, event)
            uialign(app.Objs, app.RefObj, '', 'fill');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AlignExampleUIFigure = uifigure('Visible', 'off');
            app.AlignExampleUIFigure.Name = 'Align Example';
            app.RandomizeButton = uibutton(app.AlignExampleUIFigure, 'push');
            app.RandomizeButton.ButtonPushedFcn = createCallbackFcn(app, @RandomizeButtonPushed, true);
            app.RandomizeButton.Text = 'Randomize';
            app.PlaygroundPanel = uipanel(app.AlignExampleUIFigure);
            app.PlaygroundPanel.Title = 'Playground';
            app.Button = uibutton(app.PlaygroundPanel, 'push');
            app.EditField = uieditfield(app.PlaygroundPanel, 'numeric');
            app.Label = uilabel(app.PlaygroundPanel);
            app.Label.Text = '';
            app.RefObj = uilabel(app.PlaygroundPanel);
            app.RefObj.Text = 'REF';
            app.AlignhorizontallyPanel = uipanel(app.AlignExampleUIFigure);
            app.AlignhorizontallyPanel.Title = 'Align horizontally';
            app.CenterButton = uibutton(app.AlignhorizontallyPanel, 'push');
            app.CenterButton.ButtonPushedFcn = createCallbackFcn(app, @CenterButtonPushed, true);
            app.CenterButton.Text = 'Center';
            app.LeftButton = uibutton(app.AlignhorizontallyPanel, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.RightButton = uibutton(app.AlignhorizontallyPanel, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.FillButton = uibutton(app.AlignhorizontallyPanel, 'push');
            app.FillButton.ButtonPushedFcn = createCallbackFcn(app, @FillButtonPushed, true);
            app.FillButton.Text = 'Fill';
            app.AlignverticallyPanel = uipanel(app.AlignExampleUIFigure);
            app.AlignverticallyPanel.Title = 'Align vertically';
            app.CenterButton_2 = uibutton(app.AlignverticallyPanel, 'push');
            app.CenterButton_2.ButtonPushedFcn = createCallbackFcn(app, @CenterButton_2Pushed, true);
            app.CenterButton_2.Text = 'Center';
            app.BottomButton = uibutton(app.AlignverticallyPanel, 'push');
            app.BottomButton.ButtonPushedFcn = createCallbackFcn(app, @BottomButtonPushed, true);
            app.BottomButton.Text = 'Bottom';
            app.TopButton = uibutton(app.AlignverticallyPanel, 'push');
            app.TopButton.ButtonPushedFcn = createCallbackFcn(app, @TopButtonPushed, true);
            app.TopButton.Text = 'Top';
            app.FillButton_2 = uibutton(app.AlignverticallyPanel, 'push');
            app.FillButton_2.ButtonPushedFcn = createCallbackFcn(app, @FillButton_2Pushed, true);
            app.FillButton_2.Text = 'Fill';
            app.AlighbothPanel = uipanel(app.AlignExampleUIFigure);
            app.AlighbothPanel.Title = 'Aligh both';
            app.CCButton = uibutton(app.AlighbothPanel, 'push');
            app.CCButton.ButtonPushedFcn = createCallbackFcn(app, @CCButtonPushed, true);
            app.CCButton.Text = '⊡';
            app.LCButton = uibutton(app.AlighbothPanel, 'push');
            app.LCButton.ButtonPushedFcn = createCallbackFcn(app, @LCButtonPushed, true);
            app.LCButton.Text = '←';
            app.RCButton = uibutton(app.AlighbothPanel, 'push');
            app.RCButton.ButtonPushedFcn = createCallbackFcn(app, @RCButtonPushed, true);
            app.RCButton.Text = '→';
            app.LBButton = uibutton(app.AlighbothPanel, 'push');
            app.LBButton.ButtonPushedFcn = createCallbackFcn(app, @LBButtonPushed, true);
            app.LBButton.Text = '↙';
            app.CBButton = uibutton(app.AlighbothPanel, 'push');
            app.CBButton.ButtonPushedFcn = createCallbackFcn(app, @CBButtonPushed, true);
            app.CBButton.Text = '↓';
            app.RBButton = uibutton(app.AlighbothPanel, 'push');
            app.RBButton.ButtonPushedFcn = createCallbackFcn(app, @RBButtonPushed, true);
            app.RBButton.Text = '↘';
            app.LTButton = uibutton(app.AlighbothPanel, 'push');
            app.LTButton.ButtonPushedFcn = createCallbackFcn(app, @LTButtonPushed, true);
            app.LTButton.Text = '↖';
            app.CTButton = uibutton(app.AlighbothPanel, 'push');
            app.CTButton.ButtonPushedFcn = createCallbackFcn(app, @CTButtonPushed, true);
            app.CTButton.Text = '↑';
            app.RTButton = uibutton(app.AlighbothPanel, 'push');
            app.RTButton.ButtonPushedFcn = createCallbackFcn(app, @RTButtonPushed, true);
            app.RTButton.Text = '↗';
            % Show the figure after all components are created
            app.AlignExampleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExampleAlign

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AlignExampleUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AlignExampleUIFigure)
        end
    end
end