classdef ExampleReactive < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ReactiveExampleUIFigure  matlab.ui.Figure
        Group1Panel              matlab.ui.container.Panel
        CheckBox                 matlab.ui.control.CheckBox
        EditFieldLabel           matlab.ui.control.Label
        EditField                matlab.ui.control.NumericEditField
        Group2Panel              matlab.ui.container.Panel
        EditField2Label          matlab.ui.control.Label
        EditField2               matlab.ui.control.EditField
        DropDownLabel            matlab.ui.control.Label
        DropDown                 matlab.ui.control.DropDown
        TextAreaLabel            matlab.ui.control.Label
        TextArea                 matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        Reactive % Description
        Reactive2
    end
    
    properties (Access = public)
        Prop1 = true % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Reactive = UI.Reactive('yeah');
            app.Reactive.bind(app.EditField2, app.DropDown, app.TextArea);
            app.Reactive2 = UI.Reactive(app, 'Prop1', 'Prop1');
            app.Reactive2.bind(app.EditField, app.CheckBox);
        end

        % Value changed function: DropDown, EditField2, TextArea
        function EditField2ValueChanged(app, event)
            app.Reactive.update(event);
        end

        % Value changed function: CheckBox, EditField
        function EditFieldValueChanged(app, event)
            app.Reactive2.update(event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ReactiveExampleUIFigure = uifigure('Visible', 'off');
            app.ReactiveExampleUIFigure.Name = 'Reactive Example';
            app.Group1Panel = uipanel(app.ReactiveExampleUIFigure);
            app.Group1Panel.Title = 'Group1';
            app.CheckBox = uicheckbox(app.Group1Panel);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditFieldLabel = uilabel(app.Group1Panel);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.Group1Panel, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.Group2Panel = uipanel(app.ReactiveExampleUIFigure);
            app.Group2Panel.Title = 'Group2';
            app.EditField2Label = uilabel(app.Group2Panel);
            app.EditField2Label.Text = 'Edit Field2';
            app.EditField2 = uieditfield(app.Group2Panel, 'text');
            app.EditField2.ValueChangedFcn = createCallbackFcn(app, @EditField2ValueChanged, true);
            app.DropDownLabel = uilabel(app.Group2Panel);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.Group2Panel);
            app.DropDown.Items = {'yeah', 'none'};
            app.DropDown.Editable = 'on';
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @EditField2ValueChanged, true);
            app.DropDown.Value = 'none';
            app.TextAreaLabel = uilabel(app.Group2Panel);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.Group2Panel);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @EditField2ValueChanged, true);
            % Show the figure after all components are created
            app.ReactiveExampleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExampleReactive

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ReactiveExampleUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ReactiveExampleUIFigure)
        end
    end
end