classdef TranslatorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TranslatorAppUIFigure  matlab.ui.Figure
        Panel                  matlab.ui.container.Panel
        MainGrid               matlab.ui.container.GridLayout
        TranslationTable       matlab.ui.control.Table
        TranslationTree        matlab.ui.container.Tree
        Node                   matlab.ui.container.TreeNode
        Node2                  matlab.ui.container.TreeNode
        Node3                  matlab.ui.container.TreeNode
        Node4                  matlab.ui.container.TreeNode
        GridLayout             matlab.ui.container.GridLayout
        AddTranslation         matlab.ui.control.Button
        DeleteButton           matlab.ui.control.Button
        LoadButton             matlab.ui.control.Button
        SaveButton             matlab.ui.control.Button
        LangsPanel             matlab.ui.container.Panel
    end

    
    properties (Access = private)
        Translator % Description
        TableReact
        Tree
        EditReact % Description
        LangEdit
    end
    
    methods (Access = private)
        
        function BuildTree(app)
            tr = app.Translator.getTranslation();
            app.Tree.clear();
            app.Tree.addNodes(tr);
        end
        
        function DrawLangEdit(app)
            langs = app.Translator.getLangs();
            grid = uigridlayout(app.LangsPanel, [length(langs), 2]);
            for i = 1 : length(langs)
                app.LangEdit(i) = uitextarea('Parent', grid, 'ValueChangedFcn', @(~, event)app.TranslationChanged(event));
                uilabel('Text', " " + upper(langs(i)), 'Parent', grid);
                app.TableReact.bindVar(langs(i), app.LangEdit(i));
            end
            grid.ColumnWidth{2} = 30;
            grid.ColumnSpacing = 0;
            grid.RowSpacing = 0;
            grid.Padding = 0;
        end
        
        function Load(app)
            app.Translator.loadJson('..\src\assets\i18n', 'Encoding', 'UTF-8');
            app.BuildTree();
            app.TableReact = GUIReactiveTable(app.Translator, 'getTranslationTableFull', @(tr)app.Translator.setTranslation(tr));
            app.TableReact.bind(app.TranslationTable);
            app.DrawLangEdit();
        end
        
        function Save(app)
            app.Translator.saveJson('..\src\assets\i18n', 'Encoding', 'UTF-8');
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Translator = Translator();
            app.Tree = GUITree(app.TranslationTree);
            app.Tree.LeafIcon = 'icon-mat-short-text.png';
            app.Load();
        end

        % Selection changed function: TranslationTree
        function TranslationTreeSelectionChanged(app, event)
            node = app.TranslationTree.SelectedNodes(1);
            [~, key] = app.Tree.getNodePath(node);
            data = app.TableReact.readData();
            idx = find(data.key == string(key));
            if ~isempty(idx)
                app.TableReact.select([idx(1) 1]);
            else
                app.TableReact.select([]);
            end
        end

        % Cell selection callback: TranslationTable
        function TranslationTableCellSelection(app, event)
            app.TableReact.select(event);
            key = app.TableReact.get('key');
            app.Tree.select(key);
            app.TranslationTreeSelectionChanged();
        end

        % Node text changed function: TranslationTree
        function TranslationTreeNodeTextChanged(app, event)
            node = event.Node;
            [path, newkey] = app.Tree.getNodePath(node);
            root = path(1:end-1);
            if isempty(root)
                root = string(event.PreviousText);
            else
                root = [path(1:end-1); event.PreviousText];
            end
            key = join(root, ".");
            app.Translator.renameKey(key, newkey);
            app.BuildTree();
            pause(0.1);
            app.Tree.select(newkey);
            app.TableReact.redraw();
            app.TranslationTreeSelectionChanged();
        end

        % Callback function
        function TranslationChanged(app, event)
            app.TableReact.updateVar(event);
        end

        % Cell edit callback: TranslationTable
        function TranslationTableCellEdit(app, event)
            app.TableReact.update();
            app.BuildTree();
        end

        % Button pushed function: AddTranslation
        function AddTranslationButtonPushed(app, event)
            app.TableReact.newRow('key', 'NEWKEY');
            app.BuildTree();
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            app.TableReact.deleteRow();
            app.BuildTree();
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.Load();
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.Save();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TranslatorAppUIFigure = uifigure('Visible', 'off');
            app.TranslatorAppUIFigure.Name = 'Translator App';
            app.Panel = uipanel(app.TranslatorAppUIFigure);
            app.MainGrid = uigridlayout(app.Panel);
            app.TranslationTable = uitable(app.MainGrid);
            app.TranslationTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.TranslationTable.RowName = {};
            app.TranslationTable.ColumnEditable = true;
            app.TranslationTable.CellEditCallback = createCallbackFcn(app, @TranslationTableCellEdit, true);
            app.TranslationTable.CellSelectionCallback = createCallbackFcn(app, @TranslationTableCellSelection, true);
            app.TranslationTree = uitree(app.MainGrid);
            app.TranslationTree.SelectionChangedFcn = createCallbackFcn(app, @TranslationTreeSelectionChanged, true);
            app.TranslationTree.NodeTextChangedFcn = createCallbackFcn(app, @TranslationTreeNodeTextChanged, true);
            app.TranslationTree.Editable = 'on';
            app.Node = uitreenode(app.TranslationTree);
            app.Node.Text = 'Node';
            app.Node2 = uitreenode(app.Node);
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.Node);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.Node);
            app.Node4.Text = 'Node4';
            app.GridLayout = uigridlayout(app.MainGrid);
            app.AddTranslation = uibutton(app.GridLayout, 'push');
            app.AddTranslation.ButtonPushedFcn = createCallbackFcn(app, @AddTranslationButtonPushed, true);
            app.AddTranslation.Text = 'Add';
            app.DeleteButton = uibutton(app.GridLayout, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.LoadButton = uibutton(app.GridLayout, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveButton = uibutton(app.GridLayout, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.LangsPanel = uipanel(app.MainGrid);
            % Show the figure after all components are created
            app.TranslatorAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TranslatorApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TranslatorAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TranslatorAppUIFigure)
        end
    end
end