classdef uicontrollerExample < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ControllerExampleUIFigure  matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        BinddifferentUIcomponentstothedatasourceinafewlinesofcodeLabel  matlab.ui.control.Label
        Controller2GroupPanel      matlab.ui.container.Panel
        EditField2                 matlab.ui.control.EditField
        EditField2Label            matlab.ui.control.Label
        DropDown                   matlab.ui.control.DropDown
        DropDownLabel              matlab.ui.control.Label
        ListBox                    matlab.ui.control.ListBox
        ListBoxLabel               matlab.ui.control.Label
        Controller1GroupPanel      matlab.ui.container.Panel
        StateButton                matlab.ui.control.StateButton
        EditField                  matlab.ui.control.NumericEditField
        EditFieldLabel             matlab.ui.control.Label
        CheckBox                   matlab.ui.control.CheckBox
        TextArea                   matlab.ui.control.TextArea
        TextAreaLabel              matlab.ui.control.Label
    end

    
    properties (Access = private)
        Controller1 % UI Controller
        Controller2 % UI Controller
    end
    
    properties (Access = public)
        Prop1 = true % Example app property with initial condition (must be PUBLIC)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Example 1 - one line of code, bind components to public App property
            app.Controller1 = uicontroller(DataObject=app, DataProperty='Prop1', ...
                UI=[app.EditField, app.CheckBox, app.StateButton]);
            % Example 2 - use methods to bind components to virtual value with initial condition
            app.Controller2 = uicontroller();
            app.Controller2.bindData(Data='yeah');
            app.Controller2.bindUI([app.EditField2, app.DropDown, app.TextArea, app.ListBox]);
        end

        % Value changed function: DropDown, EditField2, ListBox, TextArea
        function EditField2ValueChanged(app, event)
            % Update data in controller from UI component using event variable
            app.Controller2.update(event);
        end

        % Value changed function: CheckBox, EditField
        function EditFieldValueChanged(app, event)
            app.Controller1.update(event);
        end

        % Value changed function: StateButton
        function StateButtonValueChanged(app, event)
            app.Controller1.update(event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ControllerExampleUIFigure = uifigure('Visible', 'off');
            app.ControllerExampleUIFigure.Name = 'UI Controller Example';
            app.GridLayout = uigridlayout(app.ControllerExampleUIFigure);
            app.Controller1GroupPanel = uipanel(app.GridLayout);
            app.Controller1GroupPanel.Title = 'Controller 1 Group';
            app.CheckBox = uicheckbox(app.Controller1GroupPanel);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditFieldLabel = uilabel(app.Controller1GroupPanel);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.Controller1GroupPanel, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.StateButton = uibutton(app.Controller1GroupPanel, 'state');
            app.StateButton.ValueChangedFcn = createCallbackFcn(app, @StateButtonValueChanged, true);
            app.Controller2GroupPanel = uipanel(app.GridLayout);
            app.Controller2GroupPanel.Title = 'Controller 2 Group';
            app.TextAreaLabel = uilabel(app.Controller2GroupPanel);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.Controller2GroupPanel);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @EditField2ValueChanged, true);
            app.ListBoxLabel = uilabel(app.Controller2GroupPanel);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.Controller2GroupPanel);
            app.ListBox.Items = {'none', 'yeah'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @EditField2ValueChanged, true);
            app.ListBox.Value = 'none';
            app.DropDownLabel = uilabel(app.Controller2GroupPanel);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.Controller2GroupPanel);
            app.DropDown.Items = {'yeah', 'none'};
            app.DropDown.Editable = 'on';
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @EditField2ValueChanged, true);
            app.DropDown.Value = 'none';
            app.EditField2Label = uilabel(app.Controller2GroupPanel);
            app.EditField2Label.Text = 'Edit Field2';
            app.EditField2 = uieditfield(app.Controller2GroupPanel, 'text');
            app.EditField2.ValueChangedFcn = createCallbackFcn(app, @EditField2ValueChanged, true);
            app.BinddifferentUIcomponentstothedatasourceinafewlinesofcodeLabel = uilabel(app.GridLayout);
            app.BinddifferentUIcomponentstothedatasourceinafewlinesofcodeLabel.Text = 'Bind different UI components to the data source in a few lines of code';
            % Show the figure after all components are created
            app.ControllerExampleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = uicontrollerExample

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ControllerExampleUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ControllerExampleUIFigure)
        end
    end
end