classdef uiinputExample < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InputExampleUIFigure  matlab.ui.Figure
        OpendialogButton      matlab.ui.control.Button
        Label                 matlab.ui.control.Label
        Entered               matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpendialogButton
        function OpendialogButtonPushed(app, event)
            [val, ok] = uiinput(app.InputExampleUIFigure, {'Enter text' 'Show after input'}, {'' true});
            if ok && val{2}
                app.Entered.Visible = 'on';
                app.Label.Text = val{1};
            else
                app.Entered.Visible = 'off';
                app.Label.Text = '';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InputExampleUIFigure = uifigure('Visible', 'off');
            app.InputExampleUIFigure.Name = 'Input Example';
            app.OpendialogButton = uibutton(app.InputExampleUIFigure, 'push');
            app.OpendialogButton.ButtonPushedFcn = createCallbackFcn(app, @OpendialogButtonPushed, true);
            app.OpendialogButton.Text = 'Open dialog';
            app.Label = uilabel(app.InputExampleUIFigure);
            app.Label.Text = '';
            app.Entered = uilabel(app.InputExampleUIFigure);
            app.Entered.Visible = 'off';
            app.Entered.Text = 'You have entered:';
            % Show the figure after all components are created
            app.InputExampleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = uiinputExample

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InputExampleUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InputExampleUIFigure)
        end
    end
end