classdef SnackbarNotifierExample < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        NotificationsPanel  matlab.ui.container.Panel
    end

    
    properties (Access = private)
        Notifications % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            H = app.NotificationsPanel.Position(4);
            h = 0;
            for i = 1 : 5
                if i > 1
                    h = app.Notifications{i-1}.Root.Position(4);
                end
                app.Notifications{i} = uisnackbar(app.NotificationsPanel, "Notification" + i, 'Time', inf, 'Margin', H-80-(5+h)*(i-1));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.NotificationsPanel = uipanel(app.UIFigure);
            app.NotificationsPanel.Title = 'Notifications';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SnackbarNotifierExample

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end