classdef appDesignerProExample < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DemoExampleUIFigure  matlab.ui.Figure
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            uiinput(app.DemoExampleUIFigure, {'Reactive interfaces' 'Dialog inputs and Snackbars' 'Animation' 'UI alignment, themes, ...'},...
                {1 1 1 1}, 'Title', 'Main Features', 'Wait', false);
            uisnackbar(app.DemoExampleUIFigure, 'Create professional apps', 'time', inf,...
                'Actions', {"Let's start" @()disp(1)})
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DemoExampleUIFigure = uifigure('Visible', 'off');
            app.DemoExampleUIFigure.Name = 'Demo Example';
            % Show the figure after all components are created
            app.DemoExampleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appDesignerProExample

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DemoExampleUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DemoExampleUIFigure)
        end
    end
end