classdef uialignExample < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AlignExampleUIFigure    matlab.ui.Figure
        RandomizeButton         matlab.ui.control.Button
        Playground              matlab.ui.container.Panel
        Button                  matlab.ui.control.Button
        EditField               matlab.ui.control.NumericEditField
        Label                   matlab.ui.control.Label
        RefObj                  matlab.ui.control.Label
        AlignhorizontallyPanel  matlab.ui.container.Panel
        CenterButton            matlab.ui.control.Button
        LeftButton              matlab.ui.control.Button
        RightButton             matlab.ui.control.Button
        SameButton              matlab.ui.control.Button
        AlignverticallyPanel    matlab.ui.container.Panel
        CenterButton_2          matlab.ui.control.Button
        BottomButton            matlab.ui.control.Button
        TopButton               matlab.ui.control.Button
        SameButton_2            matlab.ui.control.Button
        AlighbothPanel          matlab.ui.container.Panel
        CCButton                matlab.ui.control.Button
        LCButton                matlab.ui.control.Button
        RCButton                matlab.ui.control.Button
        LBButton                matlab.ui.control.Button
        CBButton                matlab.ui.control.Button
        RBButton                matlab.ui.control.Button
        LTButton                matlab.ui.control.Button
        CTButton                matlab.ui.control.Button
        RTButton                matlab.ui.control.Button
        DistributePanel         matlab.ui.container.Panel
        HorizontallyButton      matlab.ui.control.Button
        VerticallyButton        matlab.ui.control.Button
        BothButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        Objs % Objects to align
        DefPoses % Default positions
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Objs = [app.Button app.EditField app.Label];
            app.DefPoses = get(app.Objs, {'Position'});
            app.DefPoses = vertcat(app.DefPoses{:});
            app.RandomizeButtonPushed();
        end

        % Button pushed function: RandomizeButton
        function RandomizeButtonPushed(app, event)
            W = app.Playground.Position(3) * 0.8;
            H = app.Playground.Position(4) * 0.8;
            poses = get(app.Objs, {'Position'});
            poses = vertcat(poses{:});
            xs = rand(size(poses, 1), 1) * W;
            ys = rand(size(poses, 1), 1) * H;
            poses = [xs ys app.DefPoses(:, [3 4])];
            set(app.Objs, {'Position'}, num2cell(poses, 2))
        end

        % Button pushed function: CCButton
        function CCButtonPushed(app, event)
            uialign(app.Objs, app.RefObj, 'center', 'center');
        end

        % Button pushed function: LCButton
        function LCButtonPushed(app, event)
            uialign(app.Objs, app.RefObj, 'left', 'center');
        end

        % Button pushed function: RCButton
        function RCButtonPushed(app, event)
            uialign(app.Objs, app.RefObj, 'right', 'center');
        end

        % Button pushed function: LBButton
        function LBButtonPushed(app, event)
            uialign(app.Objs, app.RefObj, 'left', 'bottom');
        end

        % Button pushed function: CBButton
        function CBButtonPushed(app, event)
            uialign(app.Objs, app.RefObj, 'center', 'bottom');
        end

        % Button pushed function: RBButton
        function RBButtonPushed(app, event)
            uialign(app.Objs, app.RefObj, 'right', 'bottom');
        end

        % Button pushed function: LTButton
        function LTButtonPushed(app, event)
            uialign(app.Objs, app.RefObj, 'left', 'top');
        end

        % Button pushed function: CTButton
        function CTButtonPushed(app, event)
            uialign(app.Objs, app.RefObj, 'center', 'top');
        end

        % Button pushed function: RTButton
        function RTButtonPushed(app, event)
            uialign(app.Objs, app.RefObj, 'right', 'top');
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            uialign(app.Objs, app.RefObj, 'left');
        end

        % Button pushed function: CenterButton
        function CenterButtonPushed(app, event)
            uialign(app.Objs, app.RefObj, 'center');
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            uialign(app.Objs, app.RefObj, 'right');
        end

        % Button pushed function: SameButton
        function SameButtonPushed(app, event)
            uialign(app.Objs, app.RefObj, 'same');
        end

        % Button pushed function: TopButton
        function TopButtonPushed(app, event)
            uialign(app.Objs, app.RefObj, [], 'top');
        end

        % Button pushed function: CenterButton_2
        function CenterButton_2Pushed(app, event)
            uialign(app.Objs, app.RefObj, [], 'center');
        end

        % Button pushed function: BottomButton
        function BottomButtonPushed(app, event)
            uialign(app.Objs, app.RefObj, [], 'bottom');
        end

        % Button pushed function: SameButton_2
        function SameButton_2Pushed(app, event)
            uialign(app.Objs, app.RefObj, [], 'same');
        end

        % Button pushed function: HorizontallyButton
        function HorizontallyButtonPushed(app, event)
            uialign(app.Objs, app.Playground, 'center', 'center', true, 'HorDist', 'auto');
        end

        % Button pushed function: VerticallyButton
        function VerticallyButtonPushed(app, event)
            uialign(app.Objs, app.Playground, 'center', 'center', true, 'VertDist', 10);
        end

        % Button pushed function: BothButton
        function BothButtonPushed(app, event)
            uialign(app.Objs, app.Playground, 'center', 'center', true, 'HorDist', 10, 'VertDist', 5);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AlignExampleUIFigure = uifigure('Visible', 'off');
            app.AlignExampleUIFigure.Name = 'Align Example';
            app.RandomizeButton = uibutton(app.AlignExampleUIFigure, 'push');
            app.RandomizeButton.ButtonPushedFcn = createCallbackFcn(app, @RandomizeButtonPushed, true);
            app.RandomizeButton.Text = 'Randomize';
            app.Playground = uipanel(app.AlignExampleUIFigure);
            app.Playground.Title = 'Playground';
            app.Button = uibutton(app.Playground, 'push');
            app.EditField = uieditfield(app.Playground, 'numeric');
            app.Label = uilabel(app.Playground);
            app.Label.Text = '';
            app.RefObj = uilabel(app.Playground);
            app.RefObj.Text = 'REF';
            app.AlignhorizontallyPanel = uipanel(app.AlignExampleUIFigure);
            app.AlignhorizontallyPanel.Title = 'Align horizontally';
            app.CenterButton = uibutton(app.AlignhorizontallyPanel, 'push');
            app.CenterButton.ButtonPushedFcn = createCallbackFcn(app, @CenterButtonPushed, true);
            app.CenterButton.Text = 'Center';
            app.LeftButton = uibutton(app.AlignhorizontallyPanel, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.RightButton = uibutton(app.AlignhorizontallyPanel, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.SameButton = uibutton(app.AlignhorizontallyPanel, 'push');
            app.SameButton.ButtonPushedFcn = createCallbackFcn(app, @SameButtonPushed, true);
            app.SameButton.Text = 'Same';
            app.AlignverticallyPanel = uipanel(app.AlignExampleUIFigure);
            app.AlignverticallyPanel.Title = 'Align vertically';
            app.CenterButton_2 = uibutton(app.AlignverticallyPanel, 'push');
            app.CenterButton_2.ButtonPushedFcn = createCallbackFcn(app, @CenterButton_2Pushed, true);
            app.CenterButton_2.Text = 'Center';
            app.BottomButton = uibutton(app.AlignverticallyPanel, 'push');
            app.BottomButton.ButtonPushedFcn = createCallbackFcn(app, @BottomButtonPushed, true);
            app.BottomButton.Text = 'Bottom';
            app.TopButton = uibutton(app.AlignverticallyPanel, 'push');
            app.TopButton.ButtonPushedFcn = createCallbackFcn(app, @TopButtonPushed, true);
            app.TopButton.Text = 'Top';
            app.SameButton_2 = uibutton(app.AlignverticallyPanel, 'push');
            app.SameButton_2.ButtonPushedFcn = createCallbackFcn(app, @SameButton_2Pushed, true);
            app.SameButton_2.Text = 'Same';
            app.AlighbothPanel = uipanel(app.AlignExampleUIFigure);
            app.AlighbothPanel.Title = 'Aligh both';
            app.CCButton = uibutton(app.AlighbothPanel, 'push');
            app.CCButton.ButtonPushedFcn = createCallbackFcn(app, @CCButtonPushed, true);
            app.CCButton.Text = '⊡';
            app.LCButton = uibutton(app.AlighbothPanel, 'push');
            app.LCButton.ButtonPushedFcn = createCallbackFcn(app, @LCButtonPushed, true);
            app.LCButton.Text = '←';
            app.RCButton = uibutton(app.AlighbothPanel, 'push');
            app.RCButton.ButtonPushedFcn = createCallbackFcn(app, @RCButtonPushed, true);
            app.RCButton.Text = '→';
            app.LBButton = uibutton(app.AlighbothPanel, 'push');
            app.LBButton.ButtonPushedFcn = createCallbackFcn(app, @LBButtonPushed, true);
            app.LBButton.Text = '↙';
            app.CBButton = uibutton(app.AlighbothPanel, 'push');
            app.CBButton.ButtonPushedFcn = createCallbackFcn(app, @CBButtonPushed, true);
            app.CBButton.Text = '↓';
            app.RBButton = uibutton(app.AlighbothPanel, 'push');
            app.RBButton.ButtonPushedFcn = createCallbackFcn(app, @RBButtonPushed, true);
            app.RBButton.Text = '↘';
            app.LTButton = uibutton(app.AlighbothPanel, 'push');
            app.LTButton.ButtonPushedFcn = createCallbackFcn(app, @LTButtonPushed, true);
            app.LTButton.Text = '↖';
            app.CTButton = uibutton(app.AlighbothPanel, 'push');
            app.CTButton.ButtonPushedFcn = createCallbackFcn(app, @CTButtonPushed, true);
            app.CTButton.Text = '↑';
            app.RTButton = uibutton(app.AlighbothPanel, 'push');
            app.RTButton.ButtonPushedFcn = createCallbackFcn(app, @RTButtonPushed, true);
            app.RTButton.Text = '↗';
            app.DistributePanel = uipanel(app.AlignExampleUIFigure);
            app.DistributePanel.Title = 'Distribute';
            app.HorizontallyButton = uibutton(app.DistributePanel, 'push');
            app.HorizontallyButton.ButtonPushedFcn = createCallbackFcn(app, @HorizontallyButtonPushed, true);
            app.HorizontallyButton.Text = 'Horizontally';
            app.VerticallyButton = uibutton(app.DistributePanel, 'push');
            app.VerticallyButton.ButtonPushedFcn = createCallbackFcn(app, @VerticallyButtonPushed, true);
            app.VerticallyButton.Text = 'Vertically';
            app.BothButton = uibutton(app.DistributePanel, 'push');
            app.BothButton.ButtonPushedFcn = createCallbackFcn(app, @BothButtonPushed, true);
            app.BothButton.Text = 'Both';
            % Show the figure after all components are created
            app.AlignExampleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = uialignExample

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AlignExampleUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AlignExampleUIFigure)
        end
    end
end