classdef uireactiveExample < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ReactiveExampleUIFigure  matlab.ui.Figure
        ReactiveGroup1Panel      matlab.ui.container.Panel
        CheckBox                 matlab.ui.control.CheckBox
        EditFieldLabel           matlab.ui.control.Label
        EditField                matlab.ui.control.NumericEditField
        StateButton              matlab.ui.control.StateButton
        ReactiveGroup2Panel      matlab.ui.container.Panel
        EditField2Label          matlab.ui.control.Label
        EditField2               matlab.ui.control.EditField
        DropDownLabel            matlab.ui.control.Label
        DropDown                 matlab.ui.control.DropDown
        TextAreaLabel            matlab.ui.control.Label
        TextArea                 matlab.ui.control.TextArea
        ListBoxLabel             matlab.ui.control.Label
        ListBox                  matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        Reactive1 % Description
        Reactive2
    end
    
    properties (Access = public)
        Prop1 = true % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Reactive1 = UI.Reactive(app, 'Prop1', 'Prop1');
            app.Reactive1.bind(app.EditField, app.CheckBox, app.StateButton);
            app.Reactive2 = UI.Reactive('yeah');
            app.Reactive2.bind(app.EditField2, app.DropDown, app.TextArea, app.ListBox);
        end

        % Value changed function: DropDown, EditField2, ListBox, 
        % TextArea
        function EditField2ValueChanged(app, event)
            app.Reactive2.update(event);
        end

        % Value changed function: CheckBox, EditField
        function EditFieldValueChanged(app, event)
            app.Reactive1.update(event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ReactiveExampleUIFigure = uifigure('Visible', 'off');
            app.ReactiveExampleUIFigure.Name = 'Reactive Example';
            app.ReactiveGroup1Panel = uipanel(app.ReactiveExampleUIFigure);
            app.ReactiveGroup1Panel.Title = 'Reactive Group 1';
            app.CheckBox = uicheckbox(app.ReactiveGroup1Panel);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditFieldLabel = uilabel(app.ReactiveGroup1Panel);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.ReactiveGroup1Panel, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.StateButton = uibutton(app.ReactiveGroup1Panel, 'state');
            app.ReactiveGroup2Panel = uipanel(app.ReactiveExampleUIFigure);
            app.ReactiveGroup2Panel.Title = 'Reactive Group 2';
            app.EditField2Label = uilabel(app.ReactiveGroup2Panel);
            app.EditField2Label.Text = 'Edit Field2';
            app.EditField2 = uieditfield(app.ReactiveGroup2Panel, 'text');
            app.EditField2.ValueChangedFcn = createCallbackFcn(app, @EditField2ValueChanged, true);
            app.DropDownLabel = uilabel(app.ReactiveGroup2Panel);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.ReactiveGroup2Panel);
            app.DropDown.Items = {'yeah', 'none'};
            app.DropDown.Editable = 'on';
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @EditField2ValueChanged, true);
            app.DropDown.Value = 'none';
            app.TextAreaLabel = uilabel(app.ReactiveGroup2Panel);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.ReactiveGroup2Panel);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @EditField2ValueChanged, true);
            app.ListBoxLabel = uilabel(app.ReactiveGroup2Panel);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.ReactiveGroup2Panel);
            app.ListBox.Items = {'none', 'yeah'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @EditField2ValueChanged, true);
            app.ListBox.Value = 'none';
            % Show the figure after all components are created
            app.ReactiveExampleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = uireactiveExample

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ReactiveExampleUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ReactiveExampleUIFigure)
        end
    end
end