classdef uireactivetableExample < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ReactiveTableExampleUIFigure  matlab.ui.Figure
        UITable                       matlab.ui.control.Table
        DeleterowButton               matlab.ui.control.Button
        NewrowButton                  matlab.ui.control.Button
        GetButton                     matlab.ui.control.Button
        Var3EditFieldLabel            matlab.ui.control.Label
        Var3EditField                 matlab.ui.control.EditField
        Var1EditFieldLabel            matlab.ui.control.Label
        Var1EditField                 matlab.ui.control.NumericEditField
        Var2SliderLabel               matlab.ui.control.Label
        Var2Slider                    matlab.ui.control.Slider
        MoveUpButton                  matlab.ui.control.Button
        MoveDownButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        Table % Description
    end
    
    properties (Access = public)
        Prop1 = true % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Table = uireactivetable(table(rand(5,1), rand(5,1), string(rand(5,1))));
            app.Table.bind(app.UITable);
            app.Table.bindVar('Var1', app.Var1EditField);
            app.Table.bindVar('Var2', app.Var2Slider);
            app.Table.bindVar('Var3', app.Var3EditField);
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.Table.select(event);
        end

        % Button pushed function: DeleterowButton
        function DeleterowButtonPushed(app, event)
            app.Table.deleteRow();
        end

        % Button pushed function: NewrowButton
        function NewrowButtonPushed(app, event)
            app.Table.newRow();
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            app.Table.update();
        end

        % Button pushed function: GetButton
        function GetButtonPushed(app, event)
            app.Table.getRow()
        end

        % Value changed function: Var1EditField, Var2Slider, 
        % Var3EditField
        function EditFieldValueChanged(app, event)
            app.Table.updateVar(event);
        end

        % Button pushed function: MoveUpButton
        function MoveUpButtonPushed(app, event)
            app.Table.moveRowUp();
        end

        % Button pushed function: MoveDownButton
        function MoveDownButtonPushed(app, event)
            app.Table.moveRowDown();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ReactiveTableExampleUIFigure = uifigure('Visible', 'off');
            app.ReactiveTableExampleUIFigure.Name = 'Reactive Table Example';
            app.UITable = uitable(app.ReactiveTableExampleUIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.DeleterowButton = uibutton(app.ReactiveTableExampleUIFigure, 'push');
            app.DeleterowButton.ButtonPushedFcn = createCallbackFcn(app, @DeleterowButtonPushed, true);
            app.DeleterowButton.Text = 'Delete row';
            app.NewrowButton = uibutton(app.ReactiveTableExampleUIFigure, 'push');
            app.NewrowButton.ButtonPushedFcn = createCallbackFcn(app, @NewrowButtonPushed, true);
            app.NewrowButton.Text = 'New row';
            app.GetButton = uibutton(app.ReactiveTableExampleUIFigure, 'push');
            app.GetButton.ButtonPushedFcn = createCallbackFcn(app, @GetButtonPushed, true);
            app.GetButton.Text = 'Get';
            app.Var3EditFieldLabel = uilabel(app.ReactiveTableExampleUIFigure);
            app.Var3EditFieldLabel.Text = 'Var3';
            app.Var3EditField = uieditfield(app.ReactiveTableExampleUIFigure, 'text');
            app.Var3EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.Var1EditFieldLabel = uilabel(app.ReactiveTableExampleUIFigure);
            app.Var1EditFieldLabel.Text = 'Var1';
            app.Var1EditField = uieditfield(app.ReactiveTableExampleUIFigure, 'numeric');
            app.Var1EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.Var2SliderLabel = uilabel(app.ReactiveTableExampleUIFigure);
            app.Var2SliderLabel.Text = 'Var2';
            app.Var2Slider = uislider(app.ReactiveTableExampleUIFigure);
            app.Var2Slider.Limits = [0 1];
            app.Var2Slider.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.MoveUpButton = uibutton(app.ReactiveTableExampleUIFigure, 'push');
            app.MoveUpButton.ButtonPushedFcn = createCallbackFcn(app, @MoveUpButtonPushed, true);
            app.MoveUpButton.Text = 'Move Up';
            app.MoveDownButton = uibutton(app.ReactiveTableExampleUIFigure, 'push');
            app.MoveDownButton.ButtonPushedFcn = createCallbackFcn(app, @MoveDownButtonPushed, true);
            app.MoveDownButton.Text = 'Move Down';
            % Show the figure after all components are created
            app.ReactiveTableExampleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = uireactivetableExample

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ReactiveTableExampleUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ReactiveTableExampleUIFigure)
        end
    end
end