classdef uisnackbarExample < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SnackbarExampleUIFigure  matlab.ui.Figure
        AnimationPanel           matlab.ui.container.Panel
        SlideButton              matlab.ui.control.Button
        ZoomButton               matlab.ui.control.Button
        NoanimationButton        matlab.ui.control.Button
        RollButton               matlab.ui.control.Button
        ThemePanel               matlab.ui.container.Panel
        DangerButton             matlab.ui.control.Button
        DarkButton               matlab.ui.control.Button
        LightButton              matlab.ui.control.Button
        SuccessButton            matlab.ui.control.Button
        InfoButton               matlab.ui.control.Button
        WarningButton            matlab.ui.control.Button
        NoneButton               matlab.ui.control.Button
        TypePanel                matlab.ui.container.Panel
        NotDismissibleButton     matlab.ui.control.Button
        DismissibleButton        matlab.ui.control.Button
        OtherPanel               matlab.ui.container.Panel
        LargeButton              matlab.ui.control.Button
        ScopePanel               matlab.ui.container.Panel
        LocalButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        Snackbar % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Snackbar = uisnackbar(app.SnackbarExampleUIFigure, '', 'Show', false);
        end

        % Button pushed function: LargeButton
        function LargeButtonPushed(app, event)
            app.Snackbar.Message = split('Test1Test2Test3Test4Test5Test6Test7Test8 Test9Test10Test11Test12Test13Test14Test15', ' ');
            app.Snackbar.Animation = 'slide';
            app.Snackbar.Theme = 'dark';
            app.Snackbar.Type = 'dismissible';
            app.Snackbar.show();
        end

        % Button pushed function: SlideButton
        function SlideButtonPushed(app, event)
            app.Snackbar.Message = "I have slide animation";
            app.Snackbar.Animation = 'slide';
            app.Snackbar.Theme = 'dark';
            app.Snackbar.Type = 'dismissible';
            app.Snackbar.show();
        end

        % Button pushed function: ZoomButton
        function ZoomButtonPushed(app, event)
            app.Snackbar.Message = "I have zoom animation";
            app.Snackbar.Animation = 'zoom';
            app.Snackbar.Theme = 'dark';
            app.Snackbar.Type = 'dismissible';
            app.Snackbar.show();
        end

        % Button pushed function: RollButton
        function RollButtonPushed(app, event)
            app.Snackbar.Message = "I have roll animation";
            app.Snackbar.Animation = 'roll';
            app.Snackbar.Theme = 'dark';
            app.Snackbar.Type = 'dismissible';
            app.Snackbar.show();
        end

        % Button pushed function: NoanimationButton
        function NoanimationButtonPushed(app, event)
            app.Snackbar.Message = "I'm too serious for animations";
            app.Snackbar.Animation = 'none';
            app.Snackbar.Theme = 'dark';
            app.Snackbar.Type = 'dismissible';
            app.Snackbar.show();
        end

        % Callback function
        function SlideButton_2Pushed(app, event)
            app.Snackbar.Message = "I'm persistent";
            app.Snackbar.Animation = 'slide';
            app.Snackbar.Theme = 'dark';
            app.Snackbar.Type = 'dismissible';
            app.Snackbar.show();
        end

        % Button pushed function: DarkButton
        function DarkButtonPushed(app, event)
            app.Snackbar.Message = "I'm too dark";
            app.Snackbar.Animation = 'slide';
            app.Snackbar.Theme = 'dark';
            app.Snackbar.Type = 'dismissible';
            app.Snackbar.show();
        end

        % Button pushed function: LightButton
        function LightButtonPushed(app, event)
            app.Snackbar.Message = "I'm a light";
            app.Snackbar.Animation = 'slide';
            app.Snackbar.Theme = 'light';
            app.Snackbar.Type = 'dismissible';
            app.Snackbar.show();
        end

        % Button pushed function: SuccessButton
        function SuccessButtonPushed(app, event)
            app.Snackbar.Message = "I'm successfull";
            app.Snackbar.Animation = 'slide';
            app.Snackbar.Theme = 'success';
            app.Snackbar.Type = 'dismissible';
            app.Snackbar.show();
        end

        % Button pushed function: InfoButton
        function InfoButtonPushed(app, event)
            app.Snackbar.Message = "I have some information for you";
            app.Snackbar.Animation = 'slide';
            app.Snackbar.Theme = 'info';
            app.Snackbar.Type = 'dismissible';
            app.Snackbar.show();
        end

        % Button pushed function: WarningButton
        function WarningButtonPushed(app, event)
            app.Snackbar.Message = "Please be careful";
            app.Snackbar.Animation = 'slide';
            app.Snackbar.Theme = 'warning';
            app.Snackbar.Type = 'dismissible';
            app.Snackbar.show();
        end

        % Button pushed function: DangerButton
        function DangerButtonPushed(app, event)
            app.Snackbar.Message = "I am a danger";
            app.Snackbar.Animation = 'slide';
            app.Snackbar.Theme = 'danger';
            app.Snackbar.Type = 'dismissible';
            app.Snackbar.show();
        end

        % Button pushed function: DismissibleButton
        function DismissibleButtonPushed(app, event)
            app.Snackbar.Message = "Close me if you can";
            app.Snackbar.Animation = 'slide';
            app.Snackbar.Theme = 'dark';
            app.Snackbar.Type = 'dismissible';
            app.Snackbar.show();
        end

        % Button pushed function: NotDismissibleButton
        function NotDismissibleButtonPushed(app, event)
            app.Snackbar.Message = "You can't close me";
            app.Snackbar.Animation = 'slide';
            app.Snackbar.Theme = 'dark';
            app.Snackbar.Type = 'normal';
            app.Snackbar.show();
        end

        % Button pushed function: LocalButton
        function LocalButtonPushed(app, event)
            themes = ["dark" "light" "success" "info" "warning" "danger"];
            theme = themes(randi(length(themes)));
            msg = "I'm defined in a local variable" + newline + "Spawn me 5 more times";
            s = uisnackbar(app.SnackbarExampleUIFigure, msg, 'Theme', theme);
        end

        % Button pushed function: NoneButton
        function NoneButtonPushed(app, event)
            app.Snackbar.Message = "I am boring";
            app.Snackbar.Animation = 'slide';
            app.Snackbar.Theme = 'none';
            app.Snackbar.Type = 'dismissible';
            app.Snackbar.show();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SnackbarExampleUIFigure = uifigure('Visible', 'off');
            app.SnackbarExampleUIFigure.Name = 'Snackbar Example';
            app.AnimationPanel = uipanel(app.SnackbarExampleUIFigure);
            app.AnimationPanel.Title = 'Animation';
            app.SlideButton = uibutton(app.AnimationPanel, 'push');
            app.SlideButton.ButtonPushedFcn = createCallbackFcn(app, @SlideButtonPushed, true);
            app.SlideButton.Text = 'Slide';
            app.ZoomButton = uibutton(app.AnimationPanel, 'push');
            app.ZoomButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomButtonPushed, true);
            app.ZoomButton.Text = 'Zoom';
            app.NoanimationButton = uibutton(app.AnimationPanel, 'push');
            app.NoanimationButton.ButtonPushedFcn = createCallbackFcn(app, @NoanimationButtonPushed, true);
            app.NoanimationButton.Text = 'No animation';
            app.RollButton = uibutton(app.AnimationPanel, 'push');
            app.RollButton.ButtonPushedFcn = createCallbackFcn(app, @RollButtonPushed, true);
            app.RollButton.Text = 'Roll';
            app.ThemePanel = uipanel(app.SnackbarExampleUIFigure);
            app.ThemePanel.Title = 'Theme';
            app.DangerButton = uibutton(app.ThemePanel, 'push');
            app.DangerButton.ButtonPushedFcn = createCallbackFcn(app, @DangerButtonPushed, true);
            app.DangerButton.Text = 'Danger';
            app.DarkButton = uibutton(app.ThemePanel, 'push');
            app.DarkButton.ButtonPushedFcn = createCallbackFcn(app, @DarkButtonPushed, true);
            app.DarkButton.Text = 'Dark';
            app.LightButton = uibutton(app.ThemePanel, 'push');
            app.LightButton.ButtonPushedFcn = createCallbackFcn(app, @LightButtonPushed, true);
            app.LightButton.Text = 'Light';
            app.SuccessButton = uibutton(app.ThemePanel, 'push');
            app.SuccessButton.ButtonPushedFcn = createCallbackFcn(app, @SuccessButtonPushed, true);
            app.SuccessButton.Text = 'Success';
            app.InfoButton = uibutton(app.ThemePanel, 'push');
            app.InfoButton.ButtonPushedFcn = createCallbackFcn(app, @InfoButtonPushed, true);
            app.InfoButton.Text = 'Info';
            app.WarningButton = uibutton(app.ThemePanel, 'push');
            app.WarningButton.ButtonPushedFcn = createCallbackFcn(app, @WarningButtonPushed, true);
            app.WarningButton.Text = 'Warning';
            app.NoneButton = uibutton(app.ThemePanel, 'push');
            app.NoneButton.ButtonPushedFcn = createCallbackFcn(app, @NoneButtonPushed, true);
            app.NoneButton.Text = 'None';
            app.TypePanel = uipanel(app.SnackbarExampleUIFigure);
            app.TypePanel.Title = 'Type';
            app.NotDismissibleButton = uibutton(app.TypePanel, 'push');
            app.NotDismissibleButton.ButtonPushedFcn = createCallbackFcn(app, @NotDismissibleButtonPushed, true);
            app.NotDismissibleButton.Text = 'Not Dismissible';
            app.DismissibleButton = uibutton(app.TypePanel, 'push');
            app.DismissibleButton.ButtonPushedFcn = createCallbackFcn(app, @DismissibleButtonPushed, true);
            app.DismissibleButton.Text = 'Dismissible';
            app.OtherPanel = uipanel(app.SnackbarExampleUIFigure);
            app.OtherPanel.Title = 'Other';
            app.LargeButton = uibutton(app.OtherPanel, 'push');
            app.LargeButton.ButtonPushedFcn = createCallbackFcn(app, @LargeButtonPushed, true);
            app.LargeButton.Text = 'Large';
            app.ScopePanel = uipanel(app.SnackbarExampleUIFigure);
            app.ScopePanel.Title = 'Scope';
            app.LocalButton = uibutton(app.ScopePanel, 'push');
            app.LocalButton.ButtonPushedFcn = createCallbackFcn(app, @LocalButtonPushed, true);
            app.LocalButton.Text = 'Local';
            % Show the figure after all components are created
            app.SnackbarExampleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = uisnackbarExample

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SnackbarExampleUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SnackbarExampleUIFigure)
        end
    end
end