classdef uithemeExample < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ThemeExampleUIFigure     matlab.ui.Figure
        PlaygroundPanel          matlab.ui.container.Panel
        CheckBox                 matlab.ui.control.CheckBox
        Button                   matlab.ui.control.Button
        TextAreaLabel            matlab.ui.control.Label
        TextArea                 matlab.ui.control.TextArea
        SelectThemeListBoxLabel  matlab.ui.control.Label
        SelectThemeListBox       matlab.ui.control.ListBox
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SelectThemeListBoxValueChanged();
        end

        % Value changed function: SelectThemeListBox
        function SelectThemeListBoxValueChanged(app, event)
            theme = app.SelectThemeListBox.Value;
            [c, fc] = uitheme(theme);
            obj = findobj(app.ThemeExampleUIFigure, '-Property', 'BackgroundColor', '-depth', inf);
            set(obj, 'BackgroundColor', c);
            obj = findobj(app.ThemeExampleUIFigure, '-Property', 'Color', '-depth', inf);
            set(obj, 'Color', c);
            obj = findobj(app.ThemeExampleUIFigure, '-Property', 'FontColor', '-depth', inf);
            set(obj, 'FontColor', fc);
            obj = findobj(app.ThemeExampleUIFigure, '-Property', 'ForegroundColor', '-depth', inf);
            set(obj, 'ForegroundColor', fc);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ThemeExampleUIFigure = uifigure('Visible', 'off');
            app.ThemeExampleUIFigure.Name = 'Theme Example';
            app.PlaygroundPanel = uipanel(app.ThemeExampleUIFigure);
            app.PlaygroundPanel.Title = 'Playground';
            app.CheckBox = uicheckbox(app.PlaygroundPanel);
            app.Button = uibutton(app.PlaygroundPanel, 'push');
            app.TextAreaLabel = uilabel(app.PlaygroundPanel);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.PlaygroundPanel);
            app.SelectThemeListBoxLabel = uilabel(app.ThemeExampleUIFigure);
            app.SelectThemeListBoxLabel.Text = 'Select Theme';
            app.SelectThemeListBox = uilistbox(app.ThemeExampleUIFigure);
            app.SelectThemeListBox.Items = {'none', 'dark', 'light', 'success', 'info', 'warning', 'danger'};
            app.SelectThemeListBox.ValueChangedFcn = createCallbackFcn(app, @SelectThemeListBoxValueChanged, true);
            app.SelectThemeListBox.Value = 'none';
            % Show the figure after all components are created
            app.ThemeExampleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = uithemeExample

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ThemeExampleUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ThemeExampleUIFigure)
        end
    end
end