classdef appDesignerProExample < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AppDesignerProExampleUIFigure  matlab.ui.Figure
        OpenDoc                        matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Align buton
            uialign(app.OpenDoc, app.AppDesignerProExampleUIFigure, 'center', 'center', true);
            % Apply theme
            [color, fontcolor] = uitheme('dark');
            app.AppDesignerProExampleUIFigure.Color = color;
            app.OpenDoc.BackgroundColor = color;
            app.OpenDoc.FontColor = fontcolor;
            % Show input dialog
            uiinput(app.AppDesignerProExampleUIFigure, {'Reactive interfaces' 'Input dialogs and Snackbars' 'Animation, UI alignment, themes, ...'},...
                {1 1 1}, 'Title', 'Main Features', 'Width', 250, 'Wait', false);
            % Show snackbar
            uisnackbar(app.AppDesignerProExampleUIFigure, 'Create professional apps', 'time', inf,...
                'Actions', {"Let's start" @(source,event) uidoc()});
        end

        % Button pushed function: OpenDoc
        function OpenDocButtonPushed(app, event)
            uidoc();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AppDesignerProExampleUIFigure = uifigure('Visible', 'off');
            app.AppDesignerProExampleUIFigure.Name = 'App Designer Pro Example';
            app.OpenDoc = uibutton(app.AppDesignerProExampleUIFigure, 'push');
            app.OpenDoc.ButtonPushedFcn = createCallbackFcn(app, @OpenDocButtonPushed, true);
            app.OpenDoc.Text = 'Open doc';
            % Show the figure after all components are created
            app.AppDesignerProExampleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appDesignerProExample

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AppDesignerProExampleUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AppDesignerProExampleUIFigure)
        end
    end
end