classdef uilistcontrollerExample < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ListControllerUIFigure       matlab.ui.Figure
        List3withcontrolsPanel       matlab.ui.container.Panel
        EnableMultiselectCheckBox    matlab.ui.control.CheckBox
        RenameItemButton             matlab.ui.control.Button
        ItemNameEditField            matlab.ui.control.EditField
        ItemNameEditFieldLabel       matlab.ui.control.Label
        ResetListButton              matlab.ui.control.Button
        SelectedValue                matlab.ui.control.EditField
        SelectedValueEditFieldLabel  matlab.ui.control.Label
        DropDown2                    matlab.ui.control.DropDown
        DropDown2Label               matlab.ui.control.Label
        MoveDown                     matlab.ui.control.Button
        MoveUp                       matlab.ui.control.Button
        DeleteItem                   matlab.ui.control.Button
        AddItem                      matlab.ui.control.Button
        ListBox3                     matlab.ui.control.ListBox
        ListBox3Label                matlab.ui.control.Label
        List2withItemsDataPanel      matlab.ui.container.Panel
        Spinner                      matlab.ui.control.Spinner
        SpinnerLabel                 matlab.ui.control.Label
        Slider                       matlab.ui.control.Slider
        SliderLabel                  matlab.ui.control.Label
        ListBox2                     matlab.ui.control.ListBox
        ListBox2Label                matlab.ui.control.Label
        List1Panel                   matlab.ui.container.Panel
        ListBox1                     matlab.ui.control.ListBox
        ListBox1Label                matlab.ui.control.Label
        EditField                    matlab.ui.control.EditField
        EditFieldLabel               matlab.ui.control.Label
        DropDown                     matlab.ui.control.DropDown
        DropDownLabel                matlab.ui.control.Label
        Load2                        matlab.ui.control.Button
        Load1                        matlab.ui.control.Button
    end

    
    properties (Access = private)
        ListController1 % List Controller object
        ListController2 % List Controller object
        ListController3 % List Controller object
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            items1 = "Item " + (1:5);
            % List 1
            app.ListController1 = uilistcontroller(Items=items1, ItemsUI=[app.ListBox1, app.DropDown], ...
                ValueUI=[app.ListBox1, app.DropDown, app.EditField]);
            % List 2
            items2 = "Item " + (1:7);
            items2Data = 1:7;
            app.ListController2 = uilistcontroller(Items=items2, ItemsData=items2Data, ...
                ItemsUI=app.ListBox2, ItemsDataUI=app.ListBox2, ...
                ValueUI=[app.ListBox2, app.Spinner, app.Slider]);
            % List 3
            ResetItemsButtonPushed(app);
        end

        % Button pushed function: ResetListButton
        function ResetItemsButtonPushed(app, event)
            app.ListController3 = uilistcontroller( ...
                Items="Item "+(1:3), ...
                ItemsData=(1:3), ...
                ItemsUI=[app.ListBox3, app.DropDown2], ...
                ItemsDataUI=[app.ListBox3, app.DropDown2], ...
                ValueUI=[app.ListBox3, app.DropDown2, app.SelectedValue], ...
                StateUI=[app.RenameItemButton, app.DeleteItem, app.MoveUp, app.MoveDown], ...
                StateUIProperty=["Visible", "Enable", "Enable", "Enable"], ...
                ItemUI=app.ItemNameEditField ...
                );
            app.EnableMultiselectCheckBoxValueChanged();
        end

        % Value changed function: ListBox1
        function ListBox1ValueChanged(app, event)
            app.ListController1.updateValue(event);
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            app.ListController1.updateValue(event);
        end

        % Button pushed function: Load1
        function Load1Pushed(app, event)
            items = "Item " + (1:5)';
            app.ListController1.setItems(items);
        end

        % Button pushed function: Load2
        function Load2Pushed(app, event)
            items = ["Red" "Green" "Blue"]';
            app.ListController1.setItems(items);
        end

        % Value changed function: ListBox2
        function ListBox2ValueChanged(app, event)
            app.ListController2.updateValue(event);
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            app.ListController2.updateValue(event);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.Slider.Value = round(app.Slider.Value);
            app.ListController2.updateValue(event);
        end

        % Button pushed function: AddItem
        function AddItemButtonPushed(app, event)
            app.ListController3.addItem();
        end

        % Button pushed function: DeleteItem
        function DeleteItemButtonPushed(app, event)
            app.ListController3.deleteItem();
        end

        % Button pushed function: MoveUp
        function MoveUpButtonPushed(app, event)
            app.ListController3.moveItemUp();
        end

        % Button pushed function: MoveDown
        function MoveDownButtonPushed(app, event)
            app.ListController3.moveItemDown();
        end

        % Value changed function: ListBox3
        function ListBox3ValueChanged(app, event)
            app.ListController3.updateValue(event);
        end

        % Value changed function: DropDown2
        function DropDown2ValueChanged(app, event)
            app.ListController3.updateValue(event);
        end

        % Button pushed function: RenameItemButton
        function RenameItemButtonPushed(app, event)
            app.ListController3.renameItem(app.ItemNameEditField.Value);
        end

        % Value changed function: EnableMultiselectCheckBox
        function EnableMultiselectCheckBoxValueChanged(app, event)
            isMulti = app.EnableMultiselectCheckBox.Value;
            app.ListBox3.Multiselect = isMulti;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ListControllerUIFigure = uifigure('Visible', 'off');
            app.ListControllerUIFigure.Name = 'List Controller';
            app.List1Panel = uipanel(app.ListControllerUIFigure);
            app.List1Panel.Title = 'List 1';
            app.Load1 = uibutton(app.List1Panel, 'push');
            app.Load1.ButtonPushedFcn = createCallbackFcn(app, @Load1Pushed, true);
            app.Load1.Text = 'Load Items';
            app.Load2 = uibutton(app.List1Panel, 'push');
            app.Load2.ButtonPushedFcn = createCallbackFcn(app, @Load2Pushed, true);
            app.Load2.Text = 'Load Colors';
            app.DropDownLabel = uilabel(app.List1Panel);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.List1Panel);
            app.DropDown.Items = {'Option 1', 'Option 2', 'Option 3'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.EditFieldLabel = uilabel(app.List1Panel);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.List1Panel, 'text');
            app.EditField.Editable = 'off';
            app.ListBox1Label = uilabel(app.List1Panel);
            app.ListBox1Label.Text = 'List Box 1';
            app.ListBox1 = uilistbox(app.List1Panel);
            app.ListBox1.Items = {'Item 1', 'Item 2', 'Item 3'};
            app.ListBox1.ValueChangedFcn = createCallbackFcn(app, @ListBox1ValueChanged, true);
            app.List2withItemsDataPanel = uipanel(app.ListControllerUIFigure);
            app.List2withItemsDataPanel.Title = 'List 2 with Items Data';
            app.ListBox2Label = uilabel(app.List2withItemsDataPanel);
            app.ListBox2Label.Text = 'List Box 2';
            app.ListBox2 = uilistbox(app.List2withItemsDataPanel);
            app.ListBox2.ValueChangedFcn = createCallbackFcn(app, @ListBox2ValueChanged, true);
            app.SliderLabel = uilabel(app.List2withItemsDataPanel);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.List2withItemsDataPanel);
            app.Slider.Limits = [1 7];
            app.Slider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.Slider.MajorTickLabels = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [];
            app.Slider.Value = 1;
            app.SpinnerLabel = uilabel(app.List2withItemsDataPanel);
            app.SpinnerLabel.Text = 'Spinner';
            app.Spinner = uispinner(app.List2withItemsDataPanel);
            app.Spinner.Limits = [1 10];
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner.Value = 1;
            app.List3withcontrolsPanel = uipanel(app.ListControllerUIFigure);
            app.List3withcontrolsPanel.Title = 'List 3 with controls';
            app.ListBox3Label = uilabel(app.List3withcontrolsPanel);
            app.ListBox3Label.Text = 'List Box 3';
            app.ListBox3 = uilistbox(app.List3withcontrolsPanel);
            app.ListBox3.Items = {'Item 1', 'Item 2'};
            app.ListBox3.ValueChangedFcn = createCallbackFcn(app, @ListBox3ValueChanged, true);
            app.AddItem = uibutton(app.List3withcontrolsPanel, 'push');
            app.AddItem.ButtonPushedFcn = createCallbackFcn(app, @AddItemButtonPushed, true);
            app.AddItem.Text = 'Add Item';
            app.DeleteItem = uibutton(app.List3withcontrolsPanel, 'push');
            app.DeleteItem.ButtonPushedFcn = createCallbackFcn(app, @DeleteItemButtonPushed, true);
            app.DeleteItem.Text = 'Delete Item';
            app.MoveUp = uibutton(app.List3withcontrolsPanel, 'push');
            app.MoveUp.ButtonPushedFcn = createCallbackFcn(app, @MoveUpButtonPushed, true);
            app.MoveUp.Text = 'Move Up';
            app.MoveDown = uibutton(app.List3withcontrolsPanel, 'push');
            app.MoveDown.ButtonPushedFcn = createCallbackFcn(app, @MoveDownButtonPushed, true);
            app.MoveDown.Text = 'Move Down';
            app.DropDown2Label = uilabel(app.List3withcontrolsPanel);
            app.DropDown2Label.Text = 'Drop Down2';
            app.DropDown2 = uidropdown(app.List3withcontrolsPanel);
            app.DropDown2.ValueChangedFcn = createCallbackFcn(app, @DropDown2ValueChanged, true);
            app.SelectedValueEditFieldLabel = uilabel(app.List3withcontrolsPanel);
            app.SelectedValueEditFieldLabel.Text = 'Selected Value';
            app.SelectedValue = uieditfield(app.List3withcontrolsPanel, 'text');
            app.SelectedValue.Editable = 'off';
            app.ResetListButton = uibutton(app.List3withcontrolsPanel, 'push');
            app.ResetListButton.ButtonPushedFcn = createCallbackFcn(app, @ResetItemsButtonPushed, true);
            app.ResetListButton.Text = 'Reset List';
            app.ItemNameEditFieldLabel = uilabel(app.List3withcontrolsPanel);
            app.ItemNameEditFieldLabel.Text = 'Item Name';
            app.ItemNameEditField = uieditfield(app.List3withcontrolsPanel, 'text');
            app.RenameItemButton = uibutton(app.List3withcontrolsPanel, 'push');
            app.RenameItemButton.ButtonPushedFcn = createCallbackFcn(app, @RenameItemButtonPushed, true);
            app.RenameItemButton.Text = 'Rename Item';
            app.EnableMultiselectCheckBox = uicheckbox(app.List3withcontrolsPanel);
            app.EnableMultiselectCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableMultiselectCheckBoxValueChanged, true);
            app.EnableMultiselectCheckBox.Text = 'Enable Multiselect';
            app.EnableMultiselectCheckBox.Value = true;
            % Show the figure after all components are created
            app.ListControllerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = uilistcontrollerExample

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ListControllerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ListControllerUIFigure)
        end
    end
end