classdef uitablecontrollerExample < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TableControllerExampleUIFigure  matlab.ui.Figure
        MoveDownButton                  matlab.ui.control.Button
        MoveUpButton                    matlab.ui.control.Button
        Var2Slider                      matlab.ui.control.Slider
        Var2SliderLabel                 matlab.ui.control.Label
        Var1EditField                   matlab.ui.control.NumericEditField
        Var1EditFieldLabel              matlab.ui.control.Label
        Var3EditField                   matlab.ui.control.EditField
        Var3EditFieldLabel              matlab.ui.control.Label
        GetButton                       matlab.ui.control.Button
        AddrowButton                    matlab.ui.control.Button
        DeleterowButton                 matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
    end

    
    properties (Access = private)
        TableController % UI Table Controller object
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TableController = uitablecontroller( ...
                Data=table(rand(5,1), rand(5,1), string(rand(5,1)*100)), ...
                DataUI=app.UITable, ...
                SelectionUI = app.UITable ...
                );
%             app.TableController.bindVar('Var1', app.Var1EditField);
%             app.TableController.bindVar('Var2', app.Var2Slider);
%             app.TableController.bindVar('Var3', app.Var3EditField);
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.TableController.updateSelection(event);
        end

        % Button pushed function: DeleterowButton
        function DeleterowButtonPushed(app, event)
            app.TableController.deleteRow();
        end

        % Button pushed function: AddrowButton
        function AddrowButtonPushed(app, event)
            app.TableController.addRow({rand rand rand});
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            app.TableController.updateValue(event);
        end

        % Button pushed function: GetButton
        function GetButtonPushed(app, event)
            app.TableController.getRow()
        end

        % Value changed function: Var1EditField, Var2Slider, Var3EditField
        function EditFieldValueChanged(app, event)
            app.TableController.updateVar(event);
        end

        % Button pushed function: MoveUpButton
        function MoveUpButtonPushed(app, event)
            app.TableController.moveRowUp();
        end

        % Button pushed function: MoveDownButton
        function MoveDownButtonPushed(app, event)
            app.TableController.moveRowDown();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TableControllerExampleUIFigure = uifigure('Visible', 'off');
            app.TableControllerExampleUIFigure.Name = 'Table Controller Example';
            app.UITable = uitable(app.TableControllerExampleUIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.DeleterowButton = uibutton(app.TableControllerExampleUIFigure, 'push');
            app.DeleterowButton.ButtonPushedFcn = createCallbackFcn(app, @DeleterowButtonPushed, true);
            app.DeleterowButton.Text = 'Delete row';
            app.AddrowButton = uibutton(app.TableControllerExampleUIFigure, 'push');
            app.AddrowButton.ButtonPushedFcn = createCallbackFcn(app, @AddrowButtonPushed, true);
            app.AddrowButton.Text = 'Add row';
            app.GetButton = uibutton(app.TableControllerExampleUIFigure, 'push');
            app.GetButton.ButtonPushedFcn = createCallbackFcn(app, @GetButtonPushed, true);
            app.GetButton.Text = 'Get';
            app.Var3EditFieldLabel = uilabel(app.TableControllerExampleUIFigure);
            app.Var3EditFieldLabel.Text = 'Var3';
            app.Var3EditField = uieditfield(app.TableControllerExampleUIFigure, 'text');
            app.Var3EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.Var1EditFieldLabel = uilabel(app.TableControllerExampleUIFigure);
            app.Var1EditFieldLabel.Text = 'Var1';
            app.Var1EditField = uieditfield(app.TableControllerExampleUIFigure, 'numeric');
            app.Var1EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.Var2SliderLabel = uilabel(app.TableControllerExampleUIFigure);
            app.Var2SliderLabel.Text = 'Var2';
            app.Var2Slider = uislider(app.TableControllerExampleUIFigure);
            app.Var2Slider.Limits = [0 1];
            app.Var2Slider.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.MoveUpButton = uibutton(app.TableControllerExampleUIFigure, 'push');
            app.MoveUpButton.ButtonPushedFcn = createCallbackFcn(app, @MoveUpButtonPushed, true);
            app.MoveUpButton.Text = 'Move Up';
            app.MoveDownButton = uibutton(app.TableControllerExampleUIFigure, 'push');
            app.MoveDownButton.ButtonPushedFcn = createCallbackFcn(app, @MoveDownButtonPushed, true);
            app.MoveDownButton.Text = 'Move Down';
            % Show the figure after all components are created
            app.TableControllerExampleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = uitablecontrollerExample

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TableControllerExampleUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TableControllerExampleUIFigure)
        end
    end
end